classdef Phase2_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Phase2DrawUIFigure            matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MachineControlTab             matlab.ui.container.Tab
        UnlockButton                  matlab.ui.control.Button
        HommingButton                 matlab.ui.control.Button
        ResetZeroButton               matlab.ui.control.Button
        ReturntoZeroButton            matlab.ui.control.Button
        SoftResetButton               matlab.ui.control.Button
        CheckGcodeButton              matlab.ui.control.Button
        SettingsButton                matlab.ui.control.Button
        ManualControlPanel            matlab.ui.container.Panel
        YButton                       matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        YButton_2                     matlab.ui.control.Button
        XButton_2                     matlab.ui.control.Button
        DistancemmEditFieldLabel      matlab.ui.control.Label
        DistancemmEditField           matlab.ui.control.NumericEditField
        ALARMLampLabel                matlab.ui.control.Label
        ALARMLamp                     matlab.ui.control.Lamp
        COMMPortEditFieldLabel        matlab.ui.control.Label
        COMMPortEditField             matlab.ui.control.EditField
        OpenCOMMButton                matlab.ui.control.Button
        CloseCOMMButton               matlab.ui.control.Button
        ResumeButton                  matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        DrawCircleTab                 matlab.ui.container.Tab
        CircleRadiusEditFieldLabel    matlab.ui.control.Label
        CircleRadiusEditField         matlab.ui.control.NumericEditField
        XCenterEditFieldLabel         matlab.ui.control.Label
        XCenterEditField              matlab.ui.control.NumericEditField
        YCenterEditFieldLabel         matlab.ui.control.Label
        YCenterEditField              matlab.ui.control.NumericEditField
        DrawCircleButton              matlab.ui.control.Button
        DrawSquareTab                 matlab.ui.container.Tab
        SidesLengthEditFieldLabel     matlab.ui.control.Label
        SidesLengthEditField          matlab.ui.control.NumericEditField
        XStartEditFieldLabel          matlab.ui.control.Label
        XStartEditField               matlab.ui.control.NumericEditField
        YStartEditFieldLabel          matlab.ui.control.Label
        YStartEditField               matlab.ui.control.NumericEditField
        DrawSquareButton              matlab.ui.control.Button
        WriteTextTab                  matlab.ui.container.Tab
        EnterTextEditFieldLabel       matlab.ui.control.Label
        EnterTextEditField            matlab.ui.control.EditField
        WriteButton                   matlab.ui.control.Button
        WordStartXAxisEditFieldLabel  matlab.ui.control.Label
        WordStartXAxisEditField       matlab.ui.control.NumericEditField
        WordStartYAxisEditFieldLabel  matlab.ui.control.Label
        WordStartYAxisEditField       matlab.ui.control.NumericEditField
        DrawImageTab                  matlab.ui.container.Tab
        FileDropDownLabel             matlab.ui.control.Label
        FileDropDown                  matlab.ui.control.DropDown
        SendGcodeButton               matlab.ui.control.Button
    end


    properties (Access = public)
        COMM_PORT               %Arduino COMM Port
        arduino                 %Arduino object
        
        radius                  % For "Draw Circle" - Radius of circle
        circle_centerX          %For "Draw Circle" - Center of circle (X component)
        circle_centerY          %For "Draw Circle" - Center of circle (Y component)
        
        square_startX
        square_startY
        sides_length
        
        str                     %For 'Text Write" - str to be converted to Gcode
        Xstart                  %For 'Text Write' - X location to start word
        Ystart                  %For 'Text Write' - Y location to start word
    end

    methods (Access = private)
    
        function APPsendGcode(app,GCodeCell)
            
            cellSize = size(GCodeCell,1);
            
            for n = 1:cellSize
                out = GCodeCell{n};
                if isempty(out)                         %If line empty then fill with space
                    out = ' ';
                end
                if out(1) ~= '('                        %Only read Gcode
                    fprintf(app.arduino,GCodeCell{n});
                    confirm = fgetl(app.arduino);
                    fprintf('%s \t %s',GCodeCell{n},confirm);
                end
            end
        end         

        function APPserialOpen_COMM(app,PORT)
            app.arduino = serial(PORT, 'BaudRate',115200);
            set(app.arduino,'TimeOut',120);
            fprintf('Initiating Arduino Serial Connection...')
            fopen(app.arduino);
            
            fscanf(app.arduino)                         %Print GRBL startup text
            fscanf(app.arduino)
            fscanf(app.arduino)
        end
        
        function APPserialClose_COMM(app)
            out = instrfind;
            fclose(out);
        end
 
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Currate list for "Draw Image" function (maybe move in future)
            fileStruc = dir('*.txt');
            app.FileDropDown.Items = {fileStruc.name};
        end

        % Button pushed function: DrawCircleButton
        function DrawCircleButtonPushed(app, event)
            %Draw circle
            G02plotCircle(app.radius,app.circle_centerX,app.circle_centerY);
            GcodeCell = readGCodeFile('gcode_G02circle.txt');
            APPsendGcode(app,GcodeCell);
        end

        % Value changed function: CircleRadiusEditField
        function CircleRadiusEditFieldValueChanged(app, event)
            %Set radius for "Draw Circle" Function
            value = app.CircleRadiusEditField.Value;
            app.CircleRadiusEditField.Value = value;
            app.radius = value;
        end

        % Value changed function: XCenterEditField
        function XCenterEditFieldValueChanged(app, event)
            %Set X center for "Draw Circle" function
            value = app.XCenterEditField.Value;
            app.XCenterEditField.Value = value;
            app.circle_centerX = value;
        end

        % Value changed function: YCenterEditField
        function YCenterEditFieldValueChanged(app, event)
            %Set Y center for "Draw Circle" function
            value = app.YCenterEditField.Value;
            app.YCenterEditField.Value = value;
            app.circle_centerY = value;
        end

        % Button pushed function: WriteButton
        function WriteButtonPushed(app, event)
            writeText(app.str,app.Xstart,app.Ystart);
            GcodeCell = readGCodeFile('gcode_TEXT.txt');
            APPsendGcode(app,GcodeCell);
        end

        % Value changed function: EnterTextEditField
        function EnterTextEditFieldValueChanged(app, event)
            %Set text for "Write Text" function
            value = app.EnterTextEditField.Value;
            app.EnterTextEditField.Value = value;
            app.str = value;
        end

        % Value changed function: WordStartXAxisEditField
        function WordStartXAxisEditFieldValueChanged(app, event)
            %Start of word in X for "Write Text" function
            value = app.WordStartXAxisEditField.Value;
            app.WordStartXAxisEditField.Value = value;
            app.Xstart = value + 0.001;
        end

        % Value changed function: WordStartYAxisEditField
        function WordStartYAxisEditFieldValueChanged(app, event)
            %Start of word in Y for "Write Text" function
            value = app.WordStartYAxisEditField.Value;
            app.WordStartYAxisEditField.Value = value;
            app.Ystart = value + 0.001;
        end

        % Button pushed function: UnlockButton
        function UnlockButtonPushed(app, event)
            %Unlock GRBL
            GcodeCell = {'$X'};
            app.ALARMLamp.Enable = 'off';
            APPsendGcode(app,GcodeCell);
        end

        % Button pushed function: HommingButton
        function HommingButtonPushed(app, event)
            %Begin homming sequence in GRBL
            GcodeCell = {'$H'};
            app.ALARMLamp.Enable = 'off';
            APPsendGcode(app,GcodeCell);
            %Lift pen after done
            GcodeCell = {'M3 S90'};
            APPsendGcode(app,GcodeCell);
        end

        % Button pushed function: ReturntoZeroButton
        function ReturntoZeroButtonPushed(app, event)
            %Return to zero
            GcodeCell = {'G90 X00.000 Y00.000'};
            APPsendGcode(app,GcodeCell);
        end

        % Button pushed function: SoftResetButton
        function SoftResetButtonPushed(app, event)
             %Perform "Soft Reset" in GRBL
             GcodeCell = {'^X'};
             APPsendGcode(app,GcodeCell);
        end

        % Button pushed function: SettingsButton
        function SettingsButtonPushed(app, event)
            %Display settings in command window
            GcodeCell = {'$$'};
            APPsendGcode(app,GcodeCell);
        end

        % Value changed function: COMMPortEditField
        function COMMPortEditFieldValueChanged(app, event)
            %Set COM port for Arduino
            value = app.COMMPortEditField.Value;
            app.COMMPortEditField.Value = value;
            app.COMM_PORT = value;
        end

        % Button pushed function: OpenCOMMButton
        function OpenCOMMButtonPushed(app, event)
            %Open serial communication with MATLAB and Arduino
            APPserialOpen_COMM(app,app.COMM_PORT);
        end

        % Button pushed function: CloseCOMMButton
        function CloseCOMMButtonPushed(app, event)
            %Close all serial connections with MATLAB
            APPserialClose_COMM(app);
        end

        % Value changed function: SidesLengthEditField
        function SidesLengthEditFieldValueChanged(app, event)
            %Set length of sides for "Draw Square" function
            value = app.SidesLengthEditField.Value;
            app.SidesLengthEditField.Value = value;
            app.sides_length = value;
        end

        % Value changed function: XStartEditField
        function XStartEditFieldValueChanged(app, event)
            %Set left corner X position for "Draw Square" function
            value = app.XStartEditField.Value;
            app.XStartEditField.Value = value;
            app.square_startX = value;
        end

        % Value changed function: YStartEditField
        function YStartEditFieldValueChanged(app, event)
            %Set left corner Y position for "Draw Square" function
            value = app.YStartEditField.Value;
            app.YStartEditField.Value = value;
            app.square_startY = value;
        end

        % Button pushed function: DrawSquareButton
        function DrawSquareButtonPushed(app, event)
             %Draw square 
             plotSquare(app.square_startX,app.square_startY, app.sides_length)
             GcodeCell = readGCodeFile('gcode_Square.txt');
             APPsendGcode(app,GcodeCell);
        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            %Set file to be drawn in "Draw Image" function
            value = app.FileDropDown.Value;
            app.FileDropDown.Value = value;
        end

        % Button pushed function: SendGcodeButton
        function SendGcodeButtonPushed(app, event)
            %Draw image selected for "Draw image" function
            GcodeCell = readGCodeFile(app.FileDropDown.Value);
            APPsendGcode(app,GcodeCell);
        end

        % Value changed function: DistancemmEditField
        function DistancemmEditFieldValueChanged(app, event)
            %Set distance for manual control to travel
            value = app.DistancemmEditField.Value;
            app.DistancemmEditField.Value = value;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            %Pauses current function
            GcodeCell = {'!'};
            APPsendGcode(app,GcodeCell); 
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            %Resume cycle
            GcodeCell = {'~'};
            APPsendGcode(app,GcodeCell);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            GcodeCell = {'G91',' Y',num2str(app.DistancemmEditField.Value, '%.3f\n')};
            APPsendGcode(app,GcodeCell);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            GcodeCell = {'G91',' Y',num2str(-app.DistancemmEditField.Value, '%.3f\n')};
            APPsendGcode(app,GcodeCell);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            GcodeCell = {'G91',' X',num2str(app.DistancemmEditField.Value, '%.3f\n')};
            APPsendGcode(app,GcodeCell);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            GcodeCell = {'G91',' X',num2str(-app.DistancemmEditField.Value, '%.3f\n')};
            APPsendGcode(app,GcodeCell);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Phase2DrawUIFigure = uifigure;
            app.Phase2DrawUIFigure.Name = 'Phase 2: Draw';
            app.TabGroup = uitabgroup(app.Phase2DrawUIFigure);
            app.MachineControlTab = uitab(app.TabGroup);
            app.MachineControlTab.Title = 'Machine Control';
            app.UnlockButton = uibutton(app.MachineControlTab, 'push');
            app.UnlockButton.ButtonPushedFcn = createCallbackFcn(app, @UnlockButtonPushed, true);
            app.UnlockButton.Text = 'Unlock';
            app.HommingButton = uibutton(app.MachineControlTab, 'push');
            app.HommingButton.ButtonPushedFcn = createCallbackFcn(app, @HommingButtonPushed, true);
            app.HommingButton.Text = 'Homming';
            app.ResetZeroButton = uibutton(app.MachineControlTab, 'push');
            app.ResetZeroButton.Text = 'Reset Zero';
            app.ReturntoZeroButton = uibutton(app.MachineControlTab, 'push');
            app.ReturntoZeroButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoZeroButtonPushed, true);
            app.ReturntoZeroButton.Text = 'Return to Zero';
            app.SoftResetButton = uibutton(app.MachineControlTab, 'push');
            app.SoftResetButton.ButtonPushedFcn = createCallbackFcn(app, @SoftResetButtonPushed, true);
            app.SoftResetButton.Text = 'Soft Reset';
            app.CheckGcodeButton = uibutton(app.MachineControlTab, 'push');
            app.CheckGcodeButton.Text = 'Check Gcode';
            app.SettingsButton = uibutton(app.MachineControlTab, 'push');
            app.SettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsButtonPushed, true);
            app.SettingsButton.Text = 'Settings';
            app.ManualControlPanel = uipanel(app.MachineControlTab);
            app.ManualControlPanel.Title = 'Manual Control';
            app.YButton = uibutton(app.ManualControlPanel, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+Y';
            app.XButton = uibutton(app.ManualControlPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '-X';
            app.YButton_2 = uibutton(app.ManualControlPanel, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '-Y';
            app.XButton_2 = uibutton(app.ManualControlPanel, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '+X';
            app.DistancemmEditFieldLabel = uilabel(app.ManualControlPanel);
            app.DistancemmEditFieldLabel.Text = 'Distance(mm)';
            app.DistancemmEditField = uieditfield(app.ManualControlPanel, 'numeric');
            app.DistancemmEditField.Limits = [0 200];
            app.DistancemmEditField.ValueChangedFcn = createCallbackFcn(app, @DistancemmEditFieldValueChanged, true);
            app.ALARMLampLabel = uilabel(app.MachineControlTab);
            app.ALARMLampLabel.Text = 'ALARM';
            app.ALARMLamp = uilamp(app.MachineControlTab);
            app.COMMPortEditFieldLabel = uilabel(app.MachineControlTab);
            app.COMMPortEditFieldLabel.Text = 'COMM Port';
            app.COMMPortEditField = uieditfield(app.MachineControlTab, 'text');
            app.COMMPortEditField.ValueChangedFcn = createCallbackFcn(app, @COMMPortEditFieldValueChanged, true);
            app.OpenCOMMButton = uibutton(app.MachineControlTab, 'push');
            app.OpenCOMMButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCOMMButtonPushed, true);
            app.OpenCOMMButton.Text = 'Open COMM';
            app.CloseCOMMButton = uibutton(app.MachineControlTab, 'push');
            app.CloseCOMMButton.ButtonPushedFcn = createCallbackFcn(app, @CloseCOMMButtonPushed, true);
            app.CloseCOMMButton.Text = 'Close COMM';
            app.ResumeButton = uibutton(app.MachineControlTab, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.PauseButton = uibutton(app.MachineControlTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.DrawCircleTab = uitab(app.TabGroup);
            app.DrawCircleTab.Title = 'Draw Circle';
            app.CircleRadiusEditFieldLabel = uilabel(app.DrawCircleTab);
            app.CircleRadiusEditFieldLabel.Text = 'Circle Radius';
            app.CircleRadiusEditField = uieditfield(app.DrawCircleTab, 'numeric');
            app.CircleRadiusEditField.Limits = [0 150];
            app.CircleRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @CircleRadiusEditFieldValueChanged, true);
            app.XCenterEditFieldLabel = uilabel(app.DrawCircleTab);
            app.XCenterEditFieldLabel.Text = 'X-Center';
            app.XCenterEditField = uieditfield(app.DrawCircleTab, 'numeric');
            app.XCenterEditField.Limits = [0 300];
            app.XCenterEditField.ValueChangedFcn = createCallbackFcn(app, @XCenterEditFieldValueChanged, true);
            app.YCenterEditFieldLabel = uilabel(app.DrawCircleTab);
            app.YCenterEditFieldLabel.Text = 'Y-Center';
            app.YCenterEditField = uieditfield(app.DrawCircleTab, 'numeric');
            app.YCenterEditField.Limits = [0 300];
            app.YCenterEditField.ValueChangedFcn = createCallbackFcn(app, @YCenterEditFieldValueChanged, true);
            app.DrawCircleButton = uibutton(app.DrawCircleTab, 'push');
            app.DrawCircleButton.ButtonPushedFcn = createCallbackFcn(app, @DrawCircleButtonPushed, true);
            app.DrawCircleButton.Text = 'Draw Circle';
            app.DrawSquareTab = uitab(app.TabGroup);
            app.DrawSquareTab.Title = 'Draw Square';
            app.SidesLengthEditFieldLabel = uilabel(app.DrawSquareTab);
            app.SidesLengthEditFieldLabel.Text = 'Sides Length';
            app.SidesLengthEditField = uieditfield(app.DrawSquareTab, 'numeric');
            app.SidesLengthEditField.Limits = [0 300];
            app.SidesLengthEditField.ValueChangedFcn = createCallbackFcn(app, @SidesLengthEditFieldValueChanged, true);
            app.XStartEditFieldLabel = uilabel(app.DrawSquareTab);
            app.XStartEditFieldLabel.Text = 'X Start';
            app.XStartEditField = uieditfield(app.DrawSquareTab, 'numeric');
            app.XStartEditField.Limits = [0 300];
            app.XStartEditField.ValueChangedFcn = createCallbackFcn(app, @XStartEditFieldValueChanged, true);
            app.YStartEditFieldLabel = uilabel(app.DrawSquareTab);
            app.YStartEditFieldLabel.Text = 'Y Start';
            app.YStartEditField = uieditfield(app.DrawSquareTab, 'numeric');
            app.YStartEditField.Limits = [0 300];
            app.YStartEditField.ValueChangedFcn = createCallbackFcn(app, @YStartEditFieldValueChanged, true);
            app.DrawSquareButton = uibutton(app.DrawSquareTab, 'push');
            app.DrawSquareButton.ButtonPushedFcn = createCallbackFcn(app, @DrawSquareButtonPushed, true);
            app.DrawSquareButton.Text = 'Draw Square';
            app.WriteTextTab = uitab(app.TabGroup);
            app.WriteTextTab.Title = 'Write Text';
            app.EnterTextEditFieldLabel = uilabel(app.WriteTextTab);
            app.EnterTextEditFieldLabel.Text = 'Enter Text';
            app.EnterTextEditField = uieditfield(app.WriteTextTab, 'text');
            app.EnterTextEditField.ValueChangedFcn = createCallbackFcn(app, @EnterTextEditFieldValueChanged, true);
            app.WriteButton = uibutton(app.WriteTextTab, 'push');
            app.WriteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteButtonPushed, true);
            app.WriteButton.Text = 'Write';
            app.WordStartXAxisEditFieldLabel = uilabel(app.WriteTextTab);
            app.WordStartXAxisEditFieldLabel.Text = 'Word Start (X-Axis)';
            app.WordStartXAxisEditField = uieditfield(app.WriteTextTab, 'numeric');
            app.WordStartXAxisEditField.Limits = [0 300];
            app.WordStartXAxisEditField.ValueChangedFcn = createCallbackFcn(app, @WordStartXAxisEditFieldValueChanged, true);
            app.WordStartYAxisEditFieldLabel = uilabel(app.WriteTextTab);
            app.WordStartYAxisEditFieldLabel.Text = 'Word Start (Y-Axis)';
            app.WordStartYAxisEditField = uieditfield(app.WriteTextTab, 'numeric');
            app.WordStartYAxisEditField.Limits = [0 300];
            app.WordStartYAxisEditField.ValueChangedFcn = createCallbackFcn(app, @WordStartYAxisEditFieldValueChanged, true);
            app.DrawImageTab = uitab(app.TabGroup);
            app.DrawImageTab.Title = 'Draw Image';
            app.FileDropDownLabel = uilabel(app.DrawImageTab);
            app.FileDropDownLabel.Text = 'File';
            app.FileDropDown = uidropdown(app.DrawImageTab);
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.SendGcodeButton = uibutton(app.DrawImageTab, 'push');
            app.SendGcodeButton.ButtonPushedFcn = createCallbackFcn(app, @SendGcodeButtonPushed, true);
            app.SendGcodeButton.Text = 'Send Gcode';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Phase2_v2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Phase2DrawUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Phase2DrawUIFigure)
        end
    end
end